classdef DefendTheNetwork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        NetworkHealthLamp            matlab.ui.control.Lamp
        NetworkHealthLampLabel       matlab.ui.control.Label
        NetworkSecureLabel           matlab.ui.control.Label
        AttackerBurnoutRateSlider_2  matlab.ui.control.Slider
        AttackerBurnoutRateSlider_2Label  matlab.ui.control.Label
        DefenderGrowthRateSlider_2   matlab.ui.control.Slider
        DefenderGrowthRateSlider_2Label  matlab.ui.control.Label
        RunSimulationButton          matlab.ui.control.Button
        DifficultyDropDown           matlab.ui.control.DropDown
        DifficultyDropDownLabel      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        AttackerStatsLabel           matlab.ui.control.Label
        DefenderStatsLabel           matlab.ui.control.Label
        AttackersGauge               matlab.ui.control.SemicircularGauge
        AttackersGaugeLabel          matlab.ui.control.Label
        DefendersGauge               matlab.ui.control.SemicircularGauge
        DefendersGaugeLabel          matlab.ui.control.Label
        BattleAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % Initial populations
            x0 = 9;   % Attackers
            y0 = 40;  % Defenders
            tspan = [0 50];

            % Get slider values and scale to 0–1
            alpha = app.DefenderGrowthRateSlider_2.Value / 100;  % Actually defender growth
            gamma = app.AttackerBurnoutRateSlider_2.Value / 100; % Actually attacker decay

            % Flip role definitions: x = Attackers, y = Defenders

            % Adjust attacker parameters based on difficulty
            switch app.DifficultyDropDown.Value
                case 'Easy'
                    beta = 0.015;
                    delta = 0.01;
                case 'Medium'
                    beta = 0.025;
                    delta = 0.015;
                case 'Hard'
                    beta = 0.035;
                    delta = 0.02;
            end

            % Define the Lotka-Volterra system
            f = @(t, y) [
                alpha * y(1) - beta * y(1) * y(2);         % Attackers
                delta * y(1) * y(2) - gamma * y(2)         % Defenders
            ];

            % Solve the system using ode45
            [t, pop] = ode45(f, tspan, [x0; y0]);

            % Plot the results: Blue = Defenders, Red = Attackers
            plot(app.BattleAxes, t, pop(:,2), 'b', t, pop(:,1), 'r', 'LineWidth', 2);
            legend(app.BattleAxes, {'Defenders', 'Attackers'}, 'Location', 'northeast');
            title(app.BattleAxes, 'Cyber Battle: Defenders vs. Attackers');
            xlabel(app.BattleAxes, 'Time');
            ylabel(app.BattleAxes, 'Population');
            grid(app.BattleAxes, 'on');

            % Final population values
            finalAttackers = pop(end,1);
            finalDefenders = pop(end,2);

            % Clamp values to [0, 100] for display
            maxGauge = 100;
            app.DefendersGauge.Value = min(finalDefenders, maxGauge);
            app.AttackersGauge.Value = min(finalAttackers, maxGauge);

            % Show numeric stats
            defenderMean = mean(pop(:,2));
            attackerMean = mean(pop(:,1));

            app.DefenderStatsLabel.Text = sprintf('Mean: %.1f, Final: %.1f', defenderMean, finalDefenders);
            app.AttackerStatsLabel.Text = sprintf('Mean: %.1f, Final: %.1f', attackerMean, finalAttackers);

            % Network Health Logic
            if finalDefenders > finalAttackers * 1.5
                app.NetworkHealthLamp.Color = [0 1 0];  % Green
                app.NetworkSecureLabel.Text = 'Network Secure';
            elseif finalAttackers > finalDefenders * 1.5
                app.NetworkHealthLamp.Color = [1 0 0];  % Red
                app.NetworkSecureLabel.Text = 'System Breached';
            else
                app.NetworkHealthLamp.Color = [1 1 0];  % Yellow
                app.NetworkSecureLabel.Text = 'Contested Zone';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {700, 700};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DifficultyDropDownLabel = uilabel(app.LeftPanel);
            app.DifficultyDropDownLabel.Text = 'Difficulty';
            app.DifficultyDropDown = uidropdown(app.LeftPanel);
            app.DifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.DifficultyDropDown.Tooltip = {'Controls attacker behavior: harder levels increase attack speed and decrease defender advantage.'};
            app.DifficultyDropDown.Value = 'Easy';
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.DefenderGrowthRateSlider_2Label = uilabel(app.LeftPanel);
            app.DefenderGrowthRateSlider_2Label.Text = 'Defender Growth Rate (α)';
            app.DefenderGrowthRateSlider_2 = uislider(app.LeftPanel);
            app.DefenderGrowthRateSlider_2.Tooltip = {'How fast defenders build up resources or strengthen security measures.'};
            app.AttackerBurnoutRateSlider_2Label = uilabel(app.LeftPanel);
            app.AttackerBurnoutRateSlider_2Label.Text = 'Attacker Burnout Rate (γ)';
            app.AttackerBurnoutRateSlider_2 = uislider(app.LeftPanel);
            app.AttackerBurnoutRateSlider_2.Tooltip = {'How quickly attackers lose steam or get blocked if they’re not successful.'};
            app.NetworkSecureLabel = uilabel(app.LeftPanel);
            app.NetworkSecureLabel.Text = 'Network Secure';
            app.NetworkHealthLampLabel = uilabel(app.LeftPanel);
            app.NetworkHealthLampLabel.Text = 'Network Health';
            app.NetworkHealthLamp = uilamp(app.LeftPanel);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Difficulty adjusts how aggressive attackers are. Hard = faster attackers and greater defender stress.';
            app.RightPanel = uipanel(app.GridLayout);
            app.BattleAxes = uiaxes(app.RightPanel);
            title(app.BattleAxes, 'Cyber Battle: Defender vs Attacker')
            xlabel(app.BattleAxes, 'Time')
            zlabel(app.BattleAxes, 'Z')
            app.BattleAxes.XTick = [0 2 4 6 8 10 12 24 36 48];
            app.BattleAxes.YTick = [0 20 40 60 80 100];
            app.DefendersGaugeLabel = uilabel(app.RightPanel);
            app.DefendersGaugeLabel.Text = 'Defenders';
            app.DefendersGauge = uigauge(app.RightPanel, 'semicircular');
            app.AttackersGaugeLabel = uilabel(app.RightPanel);
            app.AttackersGaugeLabel.Text = 'Attackers';
            app.AttackersGauge = uigauge(app.RightPanel, 'semicircular');
            app.DefenderStatsLabel = uilabel(app.RightPanel);
            app.DefenderStatsLabel.Text = 'DefenderStatsLabel';
            app.AttackerStatsLabel = uilabel(app.RightPanel);
            app.AttackerStatsLabel.Text = 'AttackerStatsLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefendTheNetwork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end